classdef Contour2Volume < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        Openoct3DMenu               matlab.ui.container.Menu
        ImportvolumeMenu            matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        AllvideoplotvolumeMenu      matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        CropvolumeMenu              matlab.ui.container.Menu
        SliceSliderLabel            matlab.ui.control.Label
        SliceSlider                 matlab.ui.control.Slider
        SliceSpinnerLabel           matlab.ui.control.Label
        SliceSpinner                matlab.ui.control.Spinner
        DrawsampleButton            matlab.ui.control.Button
        SamplesListBoxLabel         matlab.ui.control.Label
        SamplesListBox              matlab.ui.control.ListBox
        ExporttoWorkspaceButton     matlab.ui.control.Button
        RotateVolumeButton          matlab.ui.control.Button
        RemoveselectedsampleButton  matlab.ui.control.Button
        ShowROISwitchLabel          matlab.ui.control.Label
        ShowROISwitch               matlab.ui.control.Switch
        PreviewVolumeButton         matlab.ui.control.Button
        SmoothenVolumeSwitchLabel   matlab.ui.control.Label
        SmoothenVolumeSwitch        matlab.ui.control.Switch
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        currentVolume % Description
        shapeSamples % Description
        smoothVolume
        rawVolume
        roiWindow
        roiOverlay
        roiMask
        octFile
        volumePrevAx
        volumePrevFig
        correctedVolume
    end
    
    methods (Access = private)
        
        function loadVolume(app, varargin)
            keepShapes = false;
            volume = varargin{1};
            if nargin > 2
               if isstring(varargin{3}) 
                  keepShapes = true; 
               end
            end
            
            resetSlice(app);
            if ~keepShapes
                app.SamplesListBox.Items = {};
                app.shapeSamples.masks = {};
                app.shapeSamples.sliceIdx = [];
            end
            app.currentVolume = mat2gray(volume);
            imshow(imadjust(volume(:,:,1)), 'Parent', app.UIAxes);
            app.setSliceNumber(size(volume,3));
            
            
        end
        
        function setSliceNumber(app, n)
            app.SliceSlider.Limits = [1 n];
            app.SliceSpinner.Limits = [1 n];
        end
        
        
        function resetSlice(app)
            app.SliceSlider.Value(1);
            app.SliceSpinner.Value(1);
            
        end
        
        function setToSlice(app, n)
            app.SliceSpinner.Value = n;
            app.SliceSlider.Value = n;
            imshow(imadjust(app.currentVolume(:,:,n)), 'Parent', app.UIAxes);
            % show ROI overlay
            idx = find(app.shapeSamples.sliceIdx == n);
            if ~isempty(idx) && strcmp(app.ShowROISwitch.Value, 'On')
                hold(app.UIAxes, 'on');
                roi_mask = app.shapeSamples.masks{idx};
                roiMaskInv = ~(boolean(roi_mask)); % invert
                app.roiMask = roi_mask;
                app.roiOverlay = imshow(roiMaskInv,'Parent', app.UIAxes);
                hold(app.UIAxes, 'off');
                app.updateROIopacity();
                
            end
        end
        
        function updateROIopacity(app)
            
            alpha_map = double(app.roiMask);
            alpha_map(alpha_map ==0) = 0;
            app.roiOverlay.AlphaData = alpha_map;
            
        end
        
        function sliceIndex = getSliceIndex(app)
            sliceIndex = app.SliceSpinner.Value;
        end
        
        function MaskDefined(app,h,e)
            [mask, labels, ~] = app.roiWindow.getROIData;
            app.shapeSamples.masks{end+1} = mask;
            app.shapeSamples.labels{end+1} = labels;
            app.shapeSamples.sliceIdx(end+1) = app.SliceSpinner.Value;
            app.SamplesListBox.Items{end+1} = num2str(app.SliceSpinner.Value);
            delete(app.roiWindow);
            
            % sort the recorded samples/rois in the list
            [sortedSliceIdx, sortIdx] = sort(app.shapeSamples.sliceIdx);
            app.shapeSamples.sliceIdx = sortedSliceIdx;
            app.shapeSamples.labels = app.shapeSamples.labels(sortIdx);
            app.shapeSamples.masks = app.shapeSamples.masks(sortIdx);
            app.SamplesListBox.Items = app.SamplesListBox.Items(sortIdx);
        end
        
        function [rotatedVol, metaOut] = rotateVolume(app, vol, metaIn)
            rotatedVol = flip(permute(vol, [1,3,2]),3);
            metaOut = metaIn;
            metaOut.sizeX = metaIn.sizeY;
            metaOut.sizeY = metaIn.sizeX;
            metaOut.sizeRealX = metaIn.sizeRealY;
            metaOut.sizeRealY = metaIn.sizeRealX;
            metaOut.spacingX = metaIn.spacingY;
            metaOut.spacingY = metaIn.spacingX;
            
        end
        
        function cropMaskDefined(app, h, e)
           masks = app.shapeSamples.masks;
           [cropMask,~,~] = app.roiWindow.getROIData;
           delete(app.roiWindow);
           for i = 1:length(masks)
               app.shapeSamples.masks{i} = masks{i} & cropMask;
           end
        end


        %%%%%%%%%%%%%
        % Code adapted from Ina Fink
        
        % read OCT data and create matrix which can be further processed
        function octF = OpenFile(app, filename, path)
            filepath = fullfile(path, filename);
            randomInt = randi(100000);
            octTempPath = fullfile(tempdir, 'OCTtemporary', num2str(randomInt));
            dataFileName = 'Intensity.data';
            dataFolder = 'data';
            metaFile = 'Header.xml';
            
            unzip(filepath,octTempPath);

            
            xmlDoc = xmlread(fullfile(octTempPath, 'Header.xml'));
            octF.meta.sizeZ = str2num(xmlDoc.getElementsByTagName('SizePixel').item(0).getElementsByTagName('SizeZ').item(0).getFirstChild.getData);
            octF.meta.sizeX = str2num(xmlDoc.getElementsByTagName('SizePixel').item(0).getElementsByTagName('SizeX').item(0).getFirstChild.getData);
            octF.meta.sizeY = str2num(xmlDoc.getElementsByTagName('SizePixel').item(0).getElementsByTagName('SizeY').item(0).getFirstChild.getData);
            octF.meta.sizeRealZ = str2double(xmlDoc.getElementsByTagName('SizeReal').item(0).getElementsByTagName('SizeZ').item(0).getFirstChild.getData);
            octF.meta.sizeRealX = str2double(xmlDoc.getElementsByTagName('SizeReal').item(0).getElementsByTagName('SizeX').item(0).getFirstChild.getData);
            octF.meta.sizeRealY = str2double(xmlDoc.getElementsByTagName('SizeReal').item(0).getElementsByTagName('SizeY').item(0).getFirstChild.getData);
            octF.meta.spacingZ = str2double(xmlDoc.getElementsByTagName('SpacingZ').item(0).getFirstChild.getData);
            octF.meta.spacingX = str2double(xmlDoc.getElementsByTagName('SpacingX').item(0).getFirstChild.getData);
            octF.meta.spacingY = str2double(xmlDoc.getElementsByTagName('SpacingY').item(0).getFirstChild.getData);
            fileID = fopen(fullfile(octTempPath, dataFolder, dataFileName));
            matrix = fread(fileID,  octF.meta.sizeZ*  octF.meta.sizeX*  octF.meta.sizeY,'single'); % ZXYT order & 4 bytes, oder int32 oder uint32
            octF.volume = reshape(matrix,[  octF.meta.sizeZ,  octF.meta.sizeX,  octF.meta.sizeY]);
            octF.meta.voxelVolume =   octF.meta.spacingZ*  octF.meta.spacingX*  octF.meta.spacingY;
            octF.meta.filepath = filepath;
            
            rmdir(octTempPath, 's');
        end
        %%%%%%%%%%


        function exportAll(app, fileName, path)
            app.PreviewVolumeButtonPushed() % making sure figure with plot exists
            % (re-)set plot size
            pos = app.volumePrevFig.Position; %// gives x left, y bottom, width, height
            oldWidth = pos(3);
            oldHeight = pos(4);
            oldFontSize = app.volumePrevAx.FontSize;
            app.volumePrevFig.Position = [pos(1) pos(2) 1024 768];
            app.volumePrevAx.FontSize = 18;
            
            % save
            app.saveFigure(fileName, path);
            
            app.saveVideo(fileName, path);
            app.saveVolumesAndMeta(fileName, path);
            
            % revert figure to previous state
            app.volumePrevFig.Position = [pos(1) pos(2) oldWidth oldHeight];
            app.volumePrevAx.FontSize = oldFontSize;
        end
        
        function saveVideo(app, fileName, path)
            view(3)
            delete(findall(app.volumePrevFig,'Type','light'));
            camlight; lighting phong
            delete(findall(app.volumePrevFig,'type','annotation')); % get rid of annotations
            saveFigureVideo(fullfile(path, fileName));
        end
        
        function saveFigure(app, fileName, path)
            print(app.volumePrevFig, fullfile(path,fileName),'-dpng');
            figFileName = [fileName, '.fig'];
            savefig(app.volumePrevFig, fullfile(path, figFileName));
        end
        
        function saveVolumesAndMeta(app, fileName, path)
            shapeSamples = app.shapeSamples;
            rawVolume = app.rawVolume;
            smoothVolume = app.smoothVolume;
            meta = app.octFile.meta;
            matFileName = [fileName, '_volumes_' '.mat'];
            save(fullfile(path,matFileName),'shapeSamples','rawVolume','smoothVolume','meta');
        end
        
        function updateSamplesListBox(app)
            app.SamplesListBox.Items = arrayfun(@num2str, app.shapeSamples.sliceIdx,'UniformOutput', false);
      
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputVolume)
            if nargin > 1
                app.loadVolume(inputVolume);
            end
            app.shapeSamples.masks = {};
            app.shapeSamples.labels = {};
            app.shapeSamples.sliceIdx = [];
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = app.SliceSlider.Value;
            value = round(value);
            app.setToSlice(value);
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            value = app.SliceSpinner.Value;
            app.setToSlice(value);
        end

        % Button pushed function: DrawsampleButton
        function DrawsampleButtonPushed(app, event)
            app.roiWindow = CROIEditor(imadjust(mat2gray(app.currentVolume(:,:,app.getSliceIndex()))));
            addlistener(app.roiWindow,'MaskDefined',@app.MaskDefined);
            
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            % construct volume from cell
            x = size(app.shapeSamples.masks{1},1);
            y = size(app.shapeSamples.masks{1},2);
            z = length(app.shapeSamples.masks);
            rawVolume = flipud(reshape(cell2mat(app.shapeSamples.masks), x,y,z));
            exVolume = C2V.extrapolateVolume(rawVolume, app.shapeSamples.sliceIdx);
            correctedVolume = permute(exVolume, [3,2,1]);
            app.correctedVolume = correctedVolume;
            %  se = strel('disk',16);
            % closedVolume = imclose(exVolume,se);
            
            smoothVolume = smooth3(correctedVolume);
            
            
            assignin('base', 'shapeSamples', app.shapeSamples);
            assignin('base', 'correctedVolume', correctedVolume);
            assignin('base', 'rawVolume', rawVolume);
            assignin('base', 'smoothVolume', smoothVolume);
            assignin('base', 'meta', app.octFile.meta);
            
        end

        % Button pushed function: RotateVolumeButton
        function RotateVolumeButtonPushed(app, event)
            [rotVol, newMeta] = app.rotateVolume(app.currentVolume, app.octFile.meta);
            app.octFile.meta = newMeta;
            app.loadVolume(rotVol, 'keep shapes');
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            value = round(changingValue);
            app.setToSlice(value);
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            value = round(changingValue);
            app.setToSlice(value);
        end

        % Value changed function: SamplesListBox
        function SamplesListBoxValueChanged(app, event)
            value = app.SamplesListBox.Value;
            app.setToSlice(str2num(value));
        end

        % Menu selected function: Openoct3DMenu
        function Openoct3DMenuSelected(app, event)
            [fileName,pathName,~] = uigetfile({'*.oct;'},'Select OCT file');
            if fileName ~= 0
                octF = app.OpenFile(fileName, pathName);
                app.loadVolume(octF.volume);
                app.octFile = octF;
            end
        end

        % Button pushed function: PreviewVolumeButton
        function PreviewVolumeButtonPushed(app, event)
            if isempty(app.volumePrevFig) || ~isvalid(app.volumePrevFig)
               app.volumePrevFig = figure();
            else
                delete(findall(app.volumePrevFig,'type','annotation'));
            end
            %if isempty(app.volumePrevAx) || ~isvalid(app.volumePrevAx)
             %   app.volumePrevAx = axes(app.volumePrevFig);
            %end
            if strcmp(app.SmoothenVolumeSwitch.Value, 'On')
                app.rawVolume = C2V.getCorrectedVolume(app.shapeSamples.masks, app.shapeSamples.sliceIdx);
                app.smoothVolume = smooth3(app.rawVolume);
                app.volumePrevAx = C2V.previewVolume(app.rawVolume,app.octFile.meta, app.volumePrevAx,'smooth');
            else
                app.rawVolume = C2V.getCorrectedVolume(app.shapeSamples.masks, app.shapeSamples.sliceIdx);
                app.smoothVolume = smooth3(app.rawVolume);
                app.volumePrevAx = C2V.previewVolume(app.rawVolume,app.octFile.meta, app.volumePrevAx);
            end
        end

        % Value changed function: SmoothenVolumeSwitch
        function SmoothenVolumeSwitchValueChanged(app, event)
           % value = app.SmoothenVolumeSwitch.Value;
            app.PreviewVolumeButtonPushed([]);
        end

        % Button pushed function: RemoveselectedsampleButton
        function RemoveselectedsampleButtonPushed(app, event)
            % get the currently selected slice in the sample list
            sliceId = str2num(app.SamplesListBox.Value);
            items = cellfun(@str2num, app.SamplesListBox.Items);
            listIdx = find(items == sliceId);
            app.SamplesListBox.Items(listIdx) = []; % removes element from list
            app.shapeSamples.masks(listIdx) = [];
            app.shapeSamples.sliceIdx(listIdx) = [];
            
        end

        % Menu selected function: AllvideoplotvolumeMenu
        function AllvideoplotvolumeMenuSelected(app, event)
            filter = {'*.*'};
            [fileName, path] = uiputfile(filter);
            if fileName ~= 0
                app.exportAll(fileName, path);
            end
        end

        % Value changed function: ShowROISwitch
        function ShowROISwitchValueChanged(app, event)
            value = app.ShowROISwitch.Value;
            app.setToSlice(app.SliceSpinner.Value);
        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
         
        end

        % Menu selected function: CropvolumeMenu
        function CropvolumeMenuSelected(app, event)
               % create image of roi
            masks = app.shapeSamples.masks;
            if ~isempty(masks)
                combinedMasks = zeros(size(masks{1}));
                for i = 1:length(masks)
                    combinedMasks = combinedMasks | masks{i};
                end
                app.roiWindow = CROIEditor(combinedMasks);
                addlistener(app.roiWindow,'MaskDefined',@app.cropMaskDefined);
            
        else
            uiconfirm(app.UIFigure, 'You cannot crop the volume because you have not defined any slice samples yet.', 'Warning', 'Icon','warning');

        end
            
        end

        % Menu selected function: ImportvolumeMenu
        function ImportvolumeMenuSelected(app, event)
            % Message box: Do you want to import a volume for the currently loaded OCT data or for other data?
            currOpt = 	 'Current OCT data';
            otherOpt =	 'Other OCT data';
            withoutOpt = 'Load without OCT data';
            cancelOpt =  '';
            
            answer = questdlg('Do you want to import a volume for the currently loaded OCT data or for other data?', ...
               	'Import Volume', ...
                currOpt, otherOpt, withoutOpt, currOpt);
            
            % Handle response
            switch answer
                case currOpt
                    % everything stays the same
                case otherOpt
                    [octFileName, octDirPath] = uigetfile({'*.oct', '.OCT'},'Select OCT File to Open');
                    if octFileName ~= 0
                        octF = app.OpenFile(octFileName, octDirPath);
                        app.loadVolume(octF.volume);
                        app.octFile = octF;
                    end
                case withoutOpt
                    % wipe current data
                    
                case cancelOpt
                    % nothing to do
                    return;
            end
            % UIgetfiles: Select volume and .mat
            [volumeFileName, volumeDirPath] = uigetfile({'*.mat','.MAT'},'Select Volume File');
            % load the files
            if volumeFileName ~= 0
                S = load(fullfile(volumeDirPath, volumeFileName));
                app.shapeSamples = S.shapeSamples;
                app.octFile.meta = S.meta;
                app.updateSamplesListBox();
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Openoct3DMenu = uimenu(app.FileMenu);
            app.Openoct3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Openoct3DMenuSelected, true);
            app.Openoct3DMenu.Accelerator = 'O';
            app.Openoct3DMenu.Text = 'Open .oct (3D) ...';
            app.ImportvolumeMenu = uimenu(app.FileMenu);
            app.ImportvolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportvolumeMenuSelected, true);
            app.ImportvolumeMenu.Accelerator = 'I';
            app.ImportvolumeMenu.Text = 'Import volume...';
            app.ExportMenu = uimenu(app.UIFigure);
            app.ExportMenu.Text = 'Export';
            app.AllvideoplotvolumeMenu = uimenu(app.ExportMenu);
            app.AllvideoplotvolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @AllvideoplotvolumeMenuSelected, true);
            app.AllvideoplotvolumeMenu.Accelerator = 'e';
            app.AllvideoplotvolumeMenu.Text = 'All (video, plot, volume)...';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Text = 'Edit';
            app.CropvolumeMenu = uimenu(app.EditMenu);
            app.CropvolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @CropvolumeMenuSelected, true);
            app.CropvolumeMenu.Text = 'Crop volume...';
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSpinnerLabel = uilabel(app.UIFigure);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.UIFigure);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.DrawsampleButton = uibutton(app.UIFigure, 'push');
            app.DrawsampleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawsampleButtonPushed, true);
            app.DrawsampleButton.Text = 'Draw sample';
            app.SamplesListBoxLabel = uilabel(app.UIFigure);
            app.SamplesListBoxLabel.Text = 'Samples';
            app.SamplesListBox = uilistbox(app.UIFigure);
            app.SamplesListBox.Items = {};
            app.SamplesListBox.ValueChangedFcn = createCallbackFcn(app, @SamplesListBoxValueChanged, true);
            app.SamplesListBox.Value = {};
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.RotateVolumeButton = uibutton(app.UIFigure, 'push');
            app.RotateVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RotateVolumeButtonPushed, true);
            app.RotateVolumeButton.Text = 'Rotate Volume';
            app.RemoveselectedsampleButton = uibutton(app.UIFigure, 'push');
            app.RemoveselectedsampleButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedsampleButtonPushed, true);
            app.RemoveselectedsampleButton.Text = 'Remove selected sample';
            app.ShowROISwitchLabel = uilabel(app.UIFigure);
            app.ShowROISwitchLabel.Text = 'Show ROI';
            app.ShowROISwitch = uiswitch(app.UIFigure, 'slider');
            app.ShowROISwitch.ValueChangedFcn = createCallbackFcn(app, @ShowROISwitchValueChanged, true);
            app.PreviewVolumeButton = uibutton(app.UIFigure, 'push');
            app.PreviewVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewVolumeButtonPushed, true);
            app.PreviewVolumeButton.Text = 'Preview Volume';
            app.SmoothenVolumeSwitchLabel = uilabel(app.UIFigure);
            app.SmoothenVolumeSwitchLabel.Text = 'Smoothen Volume';
            app.SmoothenVolumeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SmoothenVolumeSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothenVolumeSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Contour2Volume(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end